classdef BinaryBatchDistillation_autoreflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        httpswwwangshumanbcomLabel      matlab.ui.control.Label
        AllrightsreservedbyAngshumanBuragohainLabel  matlab.ui.control.Label
        BatchDistillationBinaryLabel    matlab.ui.control.Label
        CLEARGRAPHSButton               matlab.ui.control.Button
        BatchDistillationofaBinaryMixtureLabel  matlab.ui.control.Label
        Component1molefractionincondensateEditField  matlab.ui.control.NumericEditField
        Component1molefractionincondensateEditFieldLabel  matlab.ui.control.Label
        Component1molefractionindistillerEditField  matlab.ui.control.NumericEditField
        Component1molefractionindistillerEditFieldLabel  matlab.ui.control.Label
        SOLVEButton                     matlab.ui.control.Button
        MolefractionofmixtureremainingNLNLoEditField  matlab.ui.control.NumericEditField
        EntermolefractionofmixtureremainingNLNLoLabel  matlab.ui.control.Label
        Initialmolefractionofcomp1EditField  matlab.ui.control.NumericEditField
        Initialmolefractionofcomp1EditFieldLabel  matlab.ui.control.Label
        OperatingTemperatureKEditField  matlab.ui.control.NumericEditField
        OperatingTemperatureKEditFieldLabel  matlab.ui.control.Label
        TotalpressurebarEditField       matlab.ui.control.NumericEditField
        TotalpressurebarEditFieldLabel  matlab.ui.control.Label
        TheSeparationfactorisEditField  matlab.ui.control.NumericEditField
        TheSeparationfactorisEditFieldLabel  matlab.ui.control.Label
        PLOTPROFILESButton              matlab.ui.control.Button
        TemperatureinKPressureinbarLabel  matlab.ui.control.Label
        AntoineConstantsforComponent1and2Label  matlab.ui.control.Label
        T2minEditField                  matlab.ui.control.NumericEditField
        T2minEditFieldLabel             matlab.ui.control.Label
        T2maxEditField                  matlab.ui.control.NumericEditField
        T2maxEditFieldLabel             matlab.ui.control.Label
        T1minEditField                  matlab.ui.control.NumericEditField
        T1minEditFieldLabel             matlab.ui.control.Label
        T1maxEditField                  matlab.ui.control.NumericEditField
        T1maxEditFieldLabel             matlab.ui.control.Label
        C2EditField                     matlab.ui.control.NumericEditField
        C2EditFieldLabel                matlab.ui.control.Label
        B2EditField                     matlab.ui.control.NumericEditField
        B2EditFieldLabel                matlab.ui.control.Label
        C1EditField                     matlab.ui.control.NumericEditField
        C1EditFieldLabel                matlab.ui.control.Label
        A2EditField                     matlab.ui.control.NumericEditField
        A2EditFieldLabel                matlab.ui.control.Label
        B1EditField                     matlab.ui.control.NumericEditField
        B1EditFieldLabel                matlab.ui.control.Label
        A1EditField                     matlab.ui.control.NumericEditField
        A1EditFieldLabel                matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        EnrichmentEditField             matlab.ui.control.NumericEditField
        EnrichmentLabel                 matlab.ui.control.Label
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PLOTPROFILESButton
        function PLOTPROFILESButtonPushed(app, event)
            %% Antoine equation for partial pressue
            % Equation of the form ln(p) = A - B/(T+C).............................(1)
            Pi = @(a,b,c,x) 10.^(a-(b./(c+x)));

            % Total pressure in bar
            P = app.TotalpressurebarEditField.Value;
            
            % Number of sample points for plotting partial pressure with Temperature
            N = 1000;
            
            % Antoine constants for the 1st component in the binary mixture (User
            % enters the component specific data here)
            A1 = app.A1EditField.Value; 
            B1 = app.B1EditField.Value; 
            C1 = app.C1EditField.Value;
            T1_min = app.T1minEditField.Value; 
            T1_max = app.T1maxEditField.Value;
            T1 = T1_min:(T1_max-T1_min)/N:T1_max;
            
            % Antione constants for the 2nd component in the binary mixture (User
            % enters the component specific data here)
            A2 = app.A2EditField.Value ; 
            B2 = app.B2EditField.Value ; 
            C2 = app.C2EditField.Value;
            T2_min = app.T2minEditField.Value ; 
            T2_max = app.T2maxEditField.Value;
            T2 = T2_min:(T2_max-T2_min)/N:T2_max;
            
            % Partial pressures for component 1 and 2
            P1 = zeros(1,N+1);
            P2 = zeros(1,N+1);
            
            for i=1:N+1
                P1(i) = Pi(A1,B1,C1,T1(i));
                P2(i) = Pi(A2,B2,C2,T2(i));
            end
            
            plot(app.UIAxes,T1,P1, 'Displayname','Component 1')
            hold(app.UIAxes,"on")
            plot(app.UIAxes,T2,P2, 'Displayname','Component 2')
            hold (app.UIAxes,"off")
            %title(app.UIAxes,"Partial pressure based on Antoine's equation")
            %xlabel(app.UIAxes,"Temperature in K");
            %ylabel(app.UIAxes,"Pressure in bar");
            legend(app.UIAxes);
            
            %% Separation factor calculation for a given temperature
            % Separation factor: W12 = P1*/P2*......................................(2)
            T = app.OperatingTemperatureKEditField.Value;                                % User enters the operating temperature here
            W = Pi(A1,B1,C1,T)/Pi(A2,B2,C2,T);      % Separation factor based on eqn.(2)  
            app.TheSeparationfactorisEditField.Value = W;
            
            %% Equilibrium lines for the mixture
            x1 = 0:0.01:1;
            line = 0:0.01:1;
            y1 = x1.*W./(x1.*(W-1)+1);
            x2 = 1.-x1;
            y2 = 1.-y1;
            
            plot(app.UIAxes2,x1,y1)
            hold(app.UIAxes2,"on")
            plot(app.UIAxes2,x2,y2)
            %title(app.UIAxes2,"Equilibrium curves")
            %xlabel(app.UIAxes2,"Mole fraction in liquid phase, xi")
            %ylabel(app.UIAxes2,"Mole fraction in vapor phase, yi")
            plot(app.UIAxes2,x1,line,'k--')
            hold(app.UIAxes2,"off")
            legend(app.UIAxes2,'Component 1','Component 2','');
            
            %% Bubble point and dew point lines for components

            % Bubble point line: x_1 = (P - P2*)/(P1* - P2*)........................(3)
            
            % Setting boundary conditions for temperature
            T_max = B2/(A2-log10(P))-C2;             % At T_max, x1 = 0
            T_min = B1/(A1-log10(P))-C1;             % At T_min, x1 = 1
            
            Tstep = 0.1;                           % Temperatures at intervals of 0.1 K
            Temp = T_min:Tstep:T_max;
            
            % Bubble point line values
            x_1 = (P - Pi(A2,B2,C2,Temp))./(Pi(A1,B1,C1,Temp) - Pi(A2,B2,C2,Temp));
            
            % Dew point line: y1 = x1P1*/P..........................................(4)
            y_1 = x_1.*Pi(A1,B1,C1,Temp)./P;
            
            plot(app.UIAxes3,x_1,Temp,'r')
            hold(app.UIAxes3,"on")
            plot(app.UIAxes3,y_1,Temp,'g')
            hold(app.UIAxes3,"off")
            %title(app.UIAxes3,"Temperature dependence")
            %xlabel(app.UIAxes3,"Molar fractions x1, y1")
            %ylabel(app.UIAxes3,"Temperature in K")
            legend(app.UIAxes3,'Bubble Point line','Dew Point line');
            
            %% Fractional distillation and molar composition at each stage of distillation
            % Calculation based on mole fraction of mixture remaining in the vessel,
            % NL_NLo
            % The equation is: 
            % NL/NLo = ((x1/x1o)^(1/(W-1))*((1-x1o)/(1-x1))^(W/(W-1))...............(5)
            
            % Given inital mole fraction of Component 1 = 0.8
            xo = app.Initialmolefractionofcomp1EditField.Value;                     % User can input initial mole fraction here 
            % Range of evaporated by initial molar ratio
            NL_NLo = 0:0.01:1;
            
            % Initialise solution matrix for x1
            xi_1 = zeros(1,length(NL_NLo));
            
            x_sol = 0:1e-5:1;   % Find solution for x1 in the range 0-1 with steps of 0.001
            
            % Simplification of the exponents in the equation
            a = 1/(W-1);
            b = W/(W-1);
            
            RHS = @(x) (((x/xo)^a)*(((1-xo)/(1-x))^b));   % RHS of eqn (5)
            
            for i=1:(length(NL_NLo))
                for j=1:length(x_sol)
                        % Relative error between LHS and RHS of the equation (5)
                    err = (RHS(x_sol(j)) - NL_NLo(i))/NL_NLo(i);
                        % If magnitude of relative error is less than 0.01
                    if (abs(err)<=1e-02)
                            % The assumed solution is the actual solution
                        xi_1(i) = x_sol(j);
                        break;
                    else
                        xi_1(i) = 0;
                    end
                end
            end
            
            yi_1 = xi_1.*W./(xi_1.*(W-1)+1);
            xi_2 = 1.-xi_1;
            yi_2 = 1.-yi_1;
      
            plot(app.UIAxes4,NL_NLo,xi_1,'b')
            hold(app.UIAxes4,"on")
            plot(app.UIAxes4,NL_NLo,xi_2,'r')
            plot(app.UIAxes4,NL_NLo,yi_1,'b--')
            plot(app.UIAxes4,NL_NLo,yi_2,'r--')
            hold(app.UIAxes4,"off")
            %title(app.UIAxes4,"Trace extent of distillation")
            %xlabel(app.UIAxes4,"NL/NLo")
            %ylabel(app.UIAxes4,"x,y (Mole fractions: x-liq.phase, y-vap.phase)")
            legend(app.UIAxes4,'x1','x2','y1','y2','','');
        end

        % Button pushed function: SOLVEButton
        function SOLVEButtonPushed(app, event)
            %% Fractional distillation and molar composition at each stage of distillation
            % Calculation based on mole fraction of mixture remaining in the vessel,
            % NL_NLo
            % The equation is: 
            % NL/NLo = ((x1/x1o)^(1/(W-1))*((1-x1o)/(1-x1))^(W/(W-1))...............(5)
            
            % Given inital mole fraction of Component 1 = 0.8
            xo = app.Initialmolefractionofcomp1EditField.Value;                     % User can input initial mole fraction here 
            % Remaining mole fraction of mixture
            NLNLo = app.MolefractionofmixtureremainingNLNLoEditField.Value;
            
            W = app.TheSeparationfactorisEditField.Value;
            
            x_sl = 0:1e-5:1;   % Find solution for x1 in the range 0-1 with steps of 0.001
            
            % Simplification of the exponents in the equation
            a = 1/(W-1);
            b = W/(W-1);
            
            RHS = @(x) (((x/xo)^a)*(((1-xo)/(1-x))^b));   % RHS of eqn (5)
            
                for j=1:length(x_sl)
                        % Relative error between LHS and RHS of the equation (5)
                    err = (RHS(x_sl(j)) - NLNLo)/NLNLo;
                        % If magnitude of relative error is less than 0.01
                    if (abs(err)<=1e-02)
                            % The assumed solution is the actual solution
                        x_1 = x_sl(j);
                        break;
                    else
                        x_1 = 0;
                    end
                end
                        
            double xc;
            xc = (xo/(1-NLNLo)) - (NLNLo*x_1/(1-NLNLo));
            app.Component1molefractionincondensateEditField.Value = xc;
            app.Component1molefractionindistillerEditField.Value = x_1;
            E = (xc/x_1 - 1)*100;
            app.EnrichmentEditField.Value = E;
            hold(app.UIAxes4,"on")
            plot(app.UIAxes4,[NLNLo,NLNLo],[0,x_1],'k:')
            plot(app.UIAxes4,[0,NLNLo],[x_1,x_1],'k:')
            hold(app.UIAxes4,"off");
            legend(app.UIAxes4,'x1','x2','y1','y2','','');
        end

        % Button pushed function: CLEARGRAPHSButton
        function CLEARGRAPHSButtonPushed(app, event)
            cla(app.UIAxes)
            legend(app.UIAxes,"off")
            cla(app.UIAxes2)
            legend(app.UIAxes2,"off")
            cla(app.UIAxes3)
            legend(app.UIAxes3,"off")
            cla(app.UIAxes4)
            legend(app.UIAxes4,"off")
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {680, 680};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {355, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.A1EditFieldLabel = uilabel(app.LeftPanel);
            app.A1EditFieldLabel.Text = 'A1';
            app.A1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.B1EditFieldLabel = uilabel(app.LeftPanel);
            app.B1EditFieldLabel.Text = 'B1';
            app.B1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.A2EditFieldLabel = uilabel(app.LeftPanel);
            app.A2EditFieldLabel.Text = 'A2';
            app.A2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.C1EditFieldLabel = uilabel(app.LeftPanel);
            app.C1EditFieldLabel.Text = 'C1';
            app.C1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.B2EditFieldLabel = uilabel(app.LeftPanel);
            app.B2EditFieldLabel.Text = 'B2';
            app.B2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.C2EditFieldLabel = uilabel(app.LeftPanel);
            app.C2EditFieldLabel.Text = 'C2';
            app.C2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.T1maxEditFieldLabel = uilabel(app.LeftPanel);
            app.T1maxEditFieldLabel.Text = 'T1 max';
            app.T1maxEditField = uieditfield(app.LeftPanel, 'numeric');
            app.T1minEditFieldLabel = uilabel(app.LeftPanel);
            app.T1minEditFieldLabel.Text = 'T1 min';
            app.T1minEditField = uieditfield(app.LeftPanel, 'numeric');
            app.T2maxEditFieldLabel = uilabel(app.LeftPanel);
            app.T2maxEditFieldLabel.Text = 'T2 max';
            app.T2maxEditField = uieditfield(app.LeftPanel, 'numeric');
            app.T2minEditFieldLabel = uilabel(app.LeftPanel);
            app.T2minEditFieldLabel.Text = 'T2 min';
            app.T2minEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AntoineConstantsforComponent1and2Label = uilabel(app.LeftPanel);
            app.AntoineConstantsforComponent1and2Label.Text = 'Antoine Constants for Component 1 and 2';
            app.TemperatureinKPressureinbarLabel = uilabel(app.LeftPanel);
            app.TemperatureinKPressureinbarLabel.Text = 'Temperature in K, Pressure in bar';
            app.PLOTPROFILESButton = uibutton(app.LeftPanel, 'push');
            app.PLOTPROFILESButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTPROFILESButtonPushed, true);
            app.PLOTPROFILESButton.Text = 'PLOT PROFILES';
            app.TheSeparationfactorisEditFieldLabel = uilabel(app.LeftPanel);
            app.TheSeparationfactorisEditFieldLabel.Text = 'The Separation factor is';
            app.TheSeparationfactorisEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TheSeparationfactorisEditField.Editable = 'off';
            app.TotalpressurebarEditFieldLabel = uilabel(app.LeftPanel);
            app.TotalpressurebarEditFieldLabel.Text = 'Total  pressure (bar)';
            app.TotalpressurebarEditField = uieditfield(app.LeftPanel, 'numeric');
            app.OperatingTemperatureKEditFieldLabel = uilabel(app.LeftPanel);
            app.OperatingTemperatureKEditFieldLabel.Text = 'Operating Temperature (K)';
            app.OperatingTemperatureKEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Initialmolefractionofcomp1EditFieldLabel = uilabel(app.LeftPanel);
            app.Initialmolefractionofcomp1EditFieldLabel.Text = 'Initial mole fraction of comp. 1';
            app.Initialmolefractionofcomp1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.EntermolefractionofmixtureremainingNLNLoLabel = uilabel(app.LeftPanel);
            app.EntermolefractionofmixtureremainingNLNLoLabel.Text = 'Enter mole fraction of mixture remaining (NL/NLo)';
            app.MolefractionofmixtureremainingNLNLoEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SOLVEButton = uibutton(app.LeftPanel, 'push');
            app.SOLVEButton.ButtonPushedFcn = createCallbackFcn(app, @SOLVEButtonPushed, true);
            app.SOLVEButton.Text = 'SOLVE';
            app.Component1molefractionindistillerEditFieldLabel = uilabel(app.LeftPanel);
            app.Component1molefractionindistillerEditFieldLabel.Text = 'Component 1 mole fraction in distiller';
            app.Component1molefractionindistillerEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Component1molefractionindistillerEditField.Editable = 'off';
            app.Component1molefractionincondensateEditFieldLabel = uilabel(app.LeftPanel);
            app.Component1molefractionincondensateEditFieldLabel.Text = 'Component 1 mole fraction in condensate';
            app.Component1molefractionincondensateEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Component1molefractionincondensateEditField.Editable = 'off';
            app.BatchDistillationofaBinaryMixtureLabel = uilabel(app.LeftPanel);
            app.BatchDistillationofaBinaryMixtureLabel.Text = 'Batch Distillation of a Binary Mixture';
            app.CLEARGRAPHSButton = uibutton(app.LeftPanel, 'push');
            app.CLEARGRAPHSButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARGRAPHSButtonPushed, true);
            app.CLEARGRAPHSButton.Text = 'CLEAR GRAPHS';
            app.BatchDistillationBinaryLabel = uilabel(app.LeftPanel);
            app.BatchDistillationBinaryLabel.Text = '© BatchDistillationBinary';
            app.AllrightsreservedbyAngshumanBuragohainLabel = uilabel(app.LeftPanel);
            app.AllrightsreservedbyAngshumanBuragohainLabel.Text = 'All rights reserved by Angshuman Buragohain';
            app.httpswwwangshumanbcomLabel = uilabel(app.LeftPanel);
            app.httpswwwangshumanbcomLabel.Text = 'https://www.angshumanb.com';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Partial pressure based on Antoine''s equation')
            xlabel(app.UIAxes, 'Temperature in K')
            ylabel(app.UIAxes, 'Pressure in bar')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Equilibrium curves')
            xlabel(app.UIAxes2, 'Mole fraction in liquid phase, xi')
            ylabel(app.UIAxes2, 'Mole fraction in vapor phase, yi')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, 'Temperature dependence')
            xlabel(app.UIAxes3, 'Molar fractions x1, y1')
            ylabel(app.UIAxes3, 'Temperature in K')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.RightPanel);
            title(app.UIAxes4, 'Trace extent of distillation')
            xlabel(app.UIAxes4, 'NL/NLo')
            ylabel(app.UIAxes4, 'x,y (Mol. frac: x-liq., y-vap.)')
            zlabel(app.UIAxes4, 'Z')
            app.EnrichmentLabel = uilabel(app.RightPanel);
            app.EnrichmentLabel.Text = 'Enrichment %';
            app.EnrichmentEditField = uieditfield(app.RightPanel, 'numeric');
            app.EnrichmentEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BinaryBatchDistillation_autoreflow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end